classdef HandGApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        AppsNameLabel       matlab.ui.control.Label
        InstructionsButton  matlab.ui.control.Button
        CameraSwitchLabel   matlab.ui.control.Label
        CameraSwitch        matlab.ui.control.Switch
        Lamp                matlab.ui.control.Lamp
        ExitButton          matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Instructions
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            vid = videoinput('winvideo',1);
            % Create blank image
            hImage = image(app.UIAxes,zeros(720,1280,3)); %If image resolution is 1280x720
            pause(2)
            % These lines set proper aspect ratio
            app.UIAxes.XLim = [0,1280];
            app.UIAxes.YLim = [0,720];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            pbaspect(app.UIAxes,[1280,720,1])
            % Preview the image
            preview(vid,hImage)
            
        end

        % Value changed function: CameraSwitch
        function CameraSwitchValueChanged(app, event)
            value = app.CameraSwitch.Value;
            if(value == "On")
               app.Lamp.Color = [0,1,0]; 
            elseif (value == "Off")
                app.Lamp.Color = [1,0,0]; 
            end
        end

        % Button pushed function: ExitButton
        function QuitButtonPushed(app, event)
            app.delete
        end

        % Button pushed function: InstructionsButton
        function InstructionsButtonPushed(app, event)
                        
            
            app.InstructionsButton.Enable = 'off';
            
            app.Instructions = Instructions;
            app.InstructionsButton.Enable = 'On';
          
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.AppsNameLabel = uilabel(app.UIFigure);
            app.AppsNameLabel.Text = 'App''s Name';
            app.InstructionsButton = uibutton(app.UIFigure, 'push');
            app.InstructionsButton.ButtonPushedFcn = createCallbackFcn(app, @InstructionsButtonPushed, true);
            app.InstructionsButton.Text = 'Instructions';
            app.CameraSwitchLabel = uilabel(app.UIFigure);
            app.CameraSwitchLabel.Text = 'Camera';
            app.CameraSwitch = uiswitch(app.UIFigure, 'slider');
            app.CameraSwitch.ValueChangedFcn = createCallbackFcn(app, @CameraSwitchValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HandGApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end