classdef HandGApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        AppsNameLabel       matlab.ui.control.Label
        InstructionsButton  matlab.ui.control.Button
        CameraSwitchLabel   matlab.ui.control.Label
        CameraSwitch        matlab.ui.control.Switch
        Lamp                matlab.ui.control.Lamp
        ExitButton          matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
        BeginButton         matlab.ui.control.Button
    end


    methods (Access = public)
        
        function noteGen(~, n)
            %Sound pack downloaded from Freesound
%----------------------------------------
%
%This pack of sounds contains sounds by the following user:
%- TEDAgame ( https://freesound.org/people/TEDAgame/ )
%
%You can find this pack online at: https://freesound.org/people/TEDAgame/packs/25405/
%
%License details
%---------------
%
%Creative Commons 0: http://creativecommons.org/publicdomain/zero/1.0/

if (n==0)
    [y,Fs] = audioread('c-6.ogg');
    sound(y,Fs);
elseif (n==1)
    [y,Fs] = audioread('c-5.ogg');
    sound(y,Fs); 
elseif (n==2)
    [y,Fs] = audioread('b7.ogg');
    sound(y,Fs);
elseif (n==3)
    [y,Fs] = audioread('b6.ogg');
    sound(y,Fs);
elseif (n==4)
    [y,Fs] = audioread('b4.ogg');
    sound(y,Fs);
else
    [y,Fs] = audioread('b4.ogg');
    sound(y,Fs);
end 
        
end


    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            vid = videoinput('winvideo',2);
            % Create blank image
            hImage = image(app.UIAxes,zeros(720,1280,3)); %If image resolution is 1280x720
            % pause(1)
            % These lines set proper aspect ratio
            app.UIAxes.XLim = [0,1280];
            app.UIAxes.YLim = [0,720];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            pbaspect(app.UIAxes,[1280,720,1])
            % Preview the image
            preview(vid,hImage)
            
            runloop = true;
videoframe = snapshot(cam);

while runloop
    img = snapshot(hImage);
    img = rgb2gray(img);
    img = imresize(img,[480,640]);
    img1 = imcomplement(imbinarize(img));
    img2 = imfill(img1,'holes'); % fill holes
    img3 = bwareaopen(img2,10000); %remove objects less
    
    SE1 =strel('disk',50);
    SE2 = strel('disk',60);
    
    img4e = imerode(img3,SE1); %image erosion
    img4d = imdilate(img4e,SE2); %image dilatio
    imgfo = img3 - img4d; % getting fingers
    
    %Make image binary
    imgfo(imgfo== -1) = 0;
    imgfo =logical(imgfo);
    imgfo = bwareaopen(imgfo,5000); %removing objects less or 5000size
    
    cc = bwconncomp(imgfo);% connecting componants
    nof = cc.NumObjects; % getting the number of fingers
    imgfog = uint8(255.*imgfo);
    
    nofs= num2str(nof);
    %insert text with no of fingers
   % imgfogrgb = insertText(imgfog, [0,0],nofs,'FontSize',30,'BoxColor','yellow','BoxOpacity',1,'TextColor','red');
    
    %step(videoplayer, imgfogrgb);
    
   % runloop=isOpen(videoplayer);%checking video player is open or closed
    noteGen(app, nof);
    pause(2);
end
%clear cam;
release(videoplayer);
            
        end

        % Value changed function: CameraSwitch
        function CameraSwitchValueChanged(app, event)
            value = app.CameraSwitch.Value;
            if(value == "On")
               app.Lamp.Color = [0,1,0]; 
            elseif (value == "Off")
                app.Lamp.Color = [1,0,0]; 
            end
        end

        % Button pushed function: ExitButton
        function QuitButtonPushed(app, event)
            app.delete
        end

        % Button pushed function: InstructionsButton
        function InstructionsButtonPushed(app, event)
                        
            
            app.InstructionsButton.Enable = 'off';
            
            app.Instructions = Instructions;
            app.InstructionsButton.Enable = 'On';
          
            
        end

        % Button pushed function: BeginButton
        function countFingers(app, event)
      %      clear;
%clc;
%cam = webcam('Microsoft® LifeCam Cinema(TM)');
%preview(cam);

%videoframe = snapshot(cam);
%frameSize = size(videoframe);
%videoplayer = vision.VideoPlayer('Position',[100 100 [frameSize(2), frameSize(1)]]);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.AppsNameLabel = uilabel(app.UIFigure);
            app.AppsNameLabel.Text = 'App''s Name';
            app.InstructionsButton = uibutton(app.UIFigure, 'push');
            app.InstructionsButton.ButtonPushedFcn = createCallbackFcn(app, @InstructionsButtonPushed, true);
            app.InstructionsButton.Text = 'Instructions';
            app.CameraSwitchLabel = uilabel(app.UIFigure);
            app.CameraSwitchLabel.Text = 'Camera';
            app.CameraSwitch = uiswitch(app.UIFigure, 'slider');
            app.CameraSwitch.ValueChangedFcn = createCallbackFcn(app, @CameraSwitchValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.BeginButton = uibutton(app.UIFigure, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @countFingers, true);
            app.BeginButton.Text = 'Begin';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HandGApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end